classdef EC < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        UIOG                        matlab.ui.control.UIAxes
        UITRANS                     matlab.ui.control.UIAxes
        OriginalButtonGroup         matlab.ui.container.ButtonGroup
        SignalButton                matlab.ui.control.RadioButton
        MagnitudButton              matlab.ui.control.RadioButton
        FaseButton                  matlab.ui.control.RadioButton
        RECButton                   matlab.ui.control.Button
        PLAYOGButton                matlab.ui.control.Button
        RESETTRANSFORMButton        matlab.ui.control.Button
        FrecBajasButtonGroup        matlab.ui.container.ButtonGroup
        HanningButton               matlab.ui.control.RadioButton
        RectangularButton           matlab.ui.control.RadioButton
        TukeyButton                 matlab.ui.control.RadioButton
        TriangularButton            matlab.ui.control.RadioButton
        magnitudSliderLabel         matlab.ui.control.Label
        FrecBajasMagnitud           matlab.ui.control.Slider
        TransformadaButtonGroup_2   matlab.ui.container.ButtonGroup
        SignalButton_2              matlab.ui.control.RadioButton
        MagnitudButton_2            matlab.ui.control.RadioButton
        FaseButton_2                matlab.ui.control.RadioButton
        PLAYTRANSButton             matlab.ui.control.Button
        SegundosSpinnerLabel        matlab.ui.control.Label
        SegundosSpinner             matlab.ui.control.Spinner
        FrecMediasBajasButtonGroup  matlab.ui.container.ButtonGroup
        HanningButton2              matlab.ui.control.RadioButton
        RectangularButton2          matlab.ui.control.RadioButton
        TukeyButton2                matlab.ui.control.RadioButton
        TriangularButton2           matlab.ui.control.RadioButton
        magnitudSlider_2Label       matlab.ui.control.Label
        FrecMediasBajasMagnitud     matlab.ui.control.Slider
        FrecMediasAltasButtonGroup  matlab.ui.container.ButtonGroup
        HanningButton3              matlab.ui.control.RadioButton
        RectangularButton3          matlab.ui.control.RadioButton
        TukeyButton3                matlab.ui.control.RadioButton
        TriangularButton3           matlab.ui.control.RadioButton
        magnitudSlider_3Label       matlab.ui.control.Label
        FrecAltasButtonGroup        matlab.ui.container.ButtonGroup
        HanningButton4              matlab.ui.control.RadioButton
        RectangularButton4          matlab.ui.control.RadioButton
        TukeyButton4                matlab.ui.control.RadioButton
        TriangularButton4           matlab.ui.control.RadioButton
        magnitudSlider_4Label       matlab.ui.control.Label
        FrecAltasMagnitud           matlab.ui.control.Slider
        notrecordingLabel           matlab.ui.control.Label
        ADDButton                   matlab.ui.control.Button
        FrecMediasAltasMagnitud     matlab.ui.control.Slider
        ADDButton_2                 matlab.ui.control.Button
        ADDButton_3                 matlab.ui.control.Button
        ADDButton_4                 matlab.ui.control.Button
        GRAPHButton                 matlab.ui.control.Button
        GRAPHButton_2               matlab.ui.control.Button
    end


    properties (Access = public)
        recObj = audiorecorder; % Creando el objeto de audio
        fs;                     % Extrayendo la frecuencia de muestreo del objeto de grabación.
        t;                      % Creando un vector de tiempo
        n;
        y;
        count = 0;
        
        datosGrabacion;         % Objeto que guarda los datos de la grabacion original.
        Fx;                     % Algoritmo para calcular los valores de la Transformada de Fourier
        Fx2;
        Fxs;                    % Corrimiento de la transformada para obtener valores negativos y positivos.
        f;                      % Creación del vector de Frecuencias

        datosTransformados;     % Objeto que guarda los datos de la grabacion original.
        Fxs2;
        Fxs2_fase;
        Fxs2_magn;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RECButton
        function RECButtonPushed(app, event)
            app.fs = app.recObj.SampleRate; % Extrayendo la frecuencia de muestreo del objeto de grabación.
            
            if app.SegundosSpinner.Value > 0
                % Recording de audio
                set(app.notrecordingLabel,'FontColor','red');
                set(app.notrecordingLabel,'Text','recording');
                recordblocking(app.recObj, app.SegundosSpinner.Value);    
                set(app.notrecordingLabel,'FontColor','#2e2828');
                set(app.notrecordingLabel,'Text','not recording');
                
                % Extracting the data (from the audio)
                app.datosGrabacion = getaudiodata(app.recObj);
                app.datosTransformados = getaudiodata(app.recObj);
                app.datosGrabacion = app.datosGrabacion ./ max(app.datosGrabacion);
                
                % Testing the audio
                sound(app.datosGrabacion,app.fs);
                
                % Process the data
                app.n = length(app.datosGrabacion);
                app.t = (0:app.n-1)/app.fs;
                app.Fx = fft(app.datosGrabacion);
                app.Fxs = fftshift(app.Fx);
                app.f = (-app.n/2:app.n/2-1)*app.fs/app.n;       
                
                app.Fx2 = fft(app.datosGrabacion);
                app.Fxs2 = fftshift(app.Fx);
                app.Fxs2_magn = abs(app.Fxs);
                app.Fxs2_fase = angle(app.Fxs);
                
            end
        end

        % Button pushed function: PLAYOGButton
        function PLAYOGButtonPushed(app, event)
              
                sound(app.datosGrabacion, app.fs);  

        end

        % Button pushed function: PLAYTRANSButton
        function PLAYTRANSButtonPushed(app, event)
               dim_fxs2_magn = size(app.Fxs2_magn);
                
                if dim_fxs2_magn(2) == 1
                    app.Fxs2_magn = app.Fxs2_magn'; 
                end
                
%                 disp('Fxs2_magn ');
%                 disp(app.Fxs2_magn );
%                 disp('Fxs2_fase ');
%                 disp(app.Fxs2_fase );
                
                app.Fxs2 = app.Fxs2_magn .* exp(1j*app.Fxs2_fase)';
                size(app.Fxs2)
                
               app.Fx2 = ifftshift(conj(app.Fxs2)); 
               app.y = real(ifft(app.Fx2)); 
               app.y = app.y./max(app.y);  
                
               sound(app.y, app.fs);
           
        end

        % Button pushed function: GRAPHButton
        function GRAPHButtonPushed(app, event)


            if app.SignalButton.Value
                plot(app.UIOG,app.t,app.datosGrabacion);
            elseif app.FaseButton.Value
                plot(app.UIOG,app.f,angle(app.Fxs));
            elseif app.MagnitudButton.Value
                plot(app.UIOG,app.f,abs(app.Fxs));
            end
        end

        % Button pushed function: ADDButton_2
        function ADDButton_2Pushed(app, event)
            
            %% Frecuencias bajas
            
            if app.HanningButton.Value
                app.Fxs2_magn = windowsXP(app.Fxs2_magn, 4, 1, 0.5, app.FrecBajasMagnitud.Value);
                
            elseif app.TukeyButton.Value
                app.Fxs2_magn = windowsXP(app.Fxs2_magn, 4, 1, 1, app.FrecBajasMagnitud.Value);       
                
            elseif app.RectangularButton.Value
                app.Fxs2_magn = windowsXP(app.Fxs2_magn, 4, 1, 0, app.FrecBajasMagnitud.Value);
            
            elseif app.TriangularButton.Value
                app.Fxs2_magn = windowsVista(app.Fxs2_magn, 4, 1, app.FrecBajasMagnitud.Value);
            end

            app.count = app.count + 1;
            %app.Fxs2_magn = abs(app.Fxs2);
            %app.Fxs2_fase = angle(app.Fxs2);
        end

        % Button pushed function: ADDButton_3
        function ADDButton_3Pushed(app, event)
            %% Frecuencias medias bajas
            
            if app.HanningButton2.Value
                app.Fxs2_magn = windowsXP(app.Fxs2_magn, 4, 2, 0.5, app.FrecMediasBajasMagnitud.Value);
                
            elseif app.TukeyButton2.Value
                app.Fxs2_magn = windowsXP(app.Fxs2_magn, 4, 2, 1, app.FrecMediasBajasMagnitud.Value); 
                
            elseif app.RectangularButton2.Value
                app.Fxs2_magn = windowsXP(app.Fxs2_magn, 4, 2, 0, app.FrecMediasBajasMagnitud.Value);
            
            elseif app.TriangularButton2.Value
                app.Fxs2_magn = windowsVista(app.Fxs2_magn, 4, 2, app.FrecMediasBajasMagnitud.Value);
            end
         
            app.count = app.count + 1;
        end

        % Button pushed function: ADDButton
        function ADDButtonPushed(app, event)
            
            %% Frecuencias medias altas
            
            if app.HanningButton3.Value
                app.Fxs2_magn = windowsXP(app.Fxs2_magn, 4, 3, 0.5, app.FrecMediasAltasMagnitud.Value);
                
            elseif app.TukeyButton3.Value
                app.Fxs2_magn = windowsXP(app.Fxs2_magn, 4, 3, 1, app.FrecMediasAltasMagnitud.Value);   
                
            elseif app.RectangularButton3.Value
                app.Fxs2_magn= windowsXP(app.Fxs2_magn, 4, 3, 0,app.FrecMediasAltasMagnitud.Value);
                
            elseif app.TriangularButton3.Value
                app.Fxs2_magn = windowsVista(app.Fxs2_magn, 4, 3, app.FrecMediasAltasMagnitud.Value);
            end
            
               app.count = app.count + 1;
        end

        % Button pushed function: ADDButton_4
        function ADDButton_4Pushed(app, event)
            
            %% Frecuencias altas
            
            if app.HanningButton4.Value
                app.Fxs2_magn = windowsXP(app.Fxs2_magn, 4, 4, 0.5, app.FrecAltasMagnitud.Value);
                
            elseif app.TukeyButton4.Value
                app.Fxs2_magn = windowsXP(app.Fxs2_magn, 4, 4, 1,app.FrecAltasMagnitud.Value);   
                
            elseif app.RectangularButton4.Value
                app.Fxs2_magn = windowsXP(app.Fxs2_magn, 4, 4, 0,app.FrecAltasMagnitud.Value);
          
            elseif app.TriangularButton4.Value
                app.Fxs2_magn = windowsVista(app.Fxs2_magn, 4, 4, app.FrecAltasMagnitud.Value);
            end
            
            app.count = app.count + 1;
            %app.Fxs2_magn = abs(app.Fxs2);
            %app.Fxs2_fase = angle(app.Fxs2);
        end

        % Button pushed function: GRAPHButton_2
        function GRAPHButton_2Pushed(app, event)


            if app.SignalButton_2.Value
                
                %magnitud multiplicada por e^{i*theta}
                
                dim_fxs2_magn = size(app.Fxs2_magn);
                
                if dim_fxs2_magn(2) == 1
                    app.Fxs2_magn = app.Fxs2_magn'; 
                end
                
%                 disp('Fxs2_magn ');
%                 disp(app.Fxs2_magn );
%                 disp('Fxs2_fase ');
%                 disp(app.Fxs2_fase );
                
                app.Fxs2 = app.Fxs2_magn .* exp(1j*app.Fxs2_fase)';
                size(app.Fxs2)
                
                app.Fx2 = ifftshift( conj(app.Fxs2) );
                app.y = real(ifft(app.Fx2)); 
                app.y = app.y./max(app.y); 
                plot(app.UITRANS, app.t, app.y);
                
            elseif app.FaseButton_2.Value
                plot(app.UITRANS, app.f, angle(app.Fxs));
                
            elseif app.MagnitudButton_2.Value
                plot(app.UITRANS, app.f, app.Fxs2_magn);
            end
        end

        % Button pushed function: RESETTRANSFORMButton
        function RESETTRANSFORMButtonPushed(app, event)
            app.datosTransformados = app.datosGrabacion;
            app.Fx2 = fft(app.datosGrabacion);
            app.Fxs2 = fftshift(app.Fx);
            app.Fxs2_magn = abs(app.Fxs);
            app.Fxs2_fase = angle(app.Fxs);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIOG = uiaxes(app.UIFigure);
            title(app.UIOG, '')
            xlabel(app.UIOG, 'X')
            ylabel(app.UIOG, 'Y')
            zlabel(app.UIOG, 'Z')
            app.UITRANS = uiaxes(app.UIFigure);
            title(app.UITRANS, '')
            xlabel(app.UITRANS, 'X')
            ylabel(app.UITRANS, 'Y')
            zlabel(app.UITRANS, 'Z')
            app.OriginalButtonGroup = uibuttongroup(app.UIFigure);
            app.OriginalButtonGroup.Title = 'Original';
            app.SignalButton = uiradiobutton(app.OriginalButtonGroup);
            app.SignalButton.Text = 'Signal';
            app.SignalButton.Value = true;
            app.MagnitudButton = uiradiobutton(app.OriginalButtonGroup);
            app.MagnitudButton.Text = 'Magnitud';
            app.FaseButton = uiradiobutton(app.OriginalButtonGroup);
            app.FaseButton.Text = 'Fase';
            app.RECButton = uibutton(app.UIFigure, 'push');
            app.RECButton.ButtonPushedFcn = createCallbackFcn(app, @RECButtonPushed, true);
            app.RECButton.Text = 'REC';
            app.PLAYOGButton = uibutton(app.UIFigure, 'push');
            app.PLAYOGButton.ButtonPushedFcn = createCallbackFcn(app, @PLAYOGButtonPushed, true);
            app.PLAYOGButton.Text = {'PLAY OG'; ''};
            app.RESETTRANSFORMButton = uibutton(app.UIFigure, 'push');
            app.RESETTRANSFORMButton.ButtonPushedFcn = createCallbackFcn(app, @RESETTRANSFORMButtonPushed, true);
            app.RESETTRANSFORMButton.Text = 'RESET TRANSFORM';
            app.FrecBajasButtonGroup = uibuttongroup(app.UIFigure);
            app.FrecBajasButtonGroup.Title = 'Frec Bajas';
            app.HanningButton = uiradiobutton(app.FrecBajasButtonGroup);
            app.HanningButton.Text = 'Hanning';
            app.HanningButton.Value = true;
            app.RectangularButton = uiradiobutton(app.FrecBajasButtonGroup);
            app.RectangularButton.Text = 'Rectangular';
            app.TukeyButton = uiradiobutton(app.FrecBajasButtonGroup);
            app.TukeyButton.Text = 'Tukey';
            app.TriangularButton = uiradiobutton(app.FrecBajasButtonGroup);
            app.TriangularButton.Text = 'Triangular';
            app.magnitudSliderLabel = uilabel(app.UIFigure);
            app.magnitudSliderLabel.Text = 'magnitud';
            app.FrecBajasMagnitud = uislider(app.UIFigure);
            app.FrecBajasMagnitud.Limits = [0 2];
            app.FrecBajasMagnitud.Value = 1;
            app.TransformadaButtonGroup_2 = uibuttongroup(app.UIFigure);
            app.TransformadaButtonGroup_2.Title = 'Transformada';
            app.SignalButton_2 = uiradiobutton(app.TransformadaButtonGroup_2);
            app.SignalButton_2.Text = 'Signal';
            app.SignalButton_2.Value = true;
            app.MagnitudButton_2 = uiradiobutton(app.TransformadaButtonGroup_2);
            app.MagnitudButton_2.Text = 'Magnitud';
            app.FaseButton_2 = uiradiobutton(app.TransformadaButtonGroup_2);
            app.FaseButton_2.Text = 'Fase';
            app.PLAYTRANSButton = uibutton(app.UIFigure, 'push');
            app.PLAYTRANSButton.ButtonPushedFcn = createCallbackFcn(app, @PLAYTRANSButtonPushed, true);
            app.PLAYTRANSButton.Text = 'PLAY TRANS';
            app.SegundosSpinnerLabel = uilabel(app.UIFigure);
            app.SegundosSpinnerLabel.Text = 'Segundos';
            app.SegundosSpinner = uispinner(app.UIFigure);
            app.SegundosSpinner.Limits = [1 Inf];
            app.SegundosSpinner.Value = 1;
            app.FrecMediasBajasButtonGroup = uibuttongroup(app.UIFigure);
            app.FrecMediasBajasButtonGroup.Title = 'Frec Medias Bajas';
            app.HanningButton2 = uiradiobutton(app.FrecMediasBajasButtonGroup);
            app.HanningButton2.Text = 'Hanning';
            app.HanningButton2.Value = true;
            app.RectangularButton2 = uiradiobutton(app.FrecMediasBajasButtonGroup);
            app.RectangularButton2.Text = 'Rectangular';
            app.TukeyButton2 = uiradiobutton(app.FrecMediasBajasButtonGroup);
            app.TukeyButton2.Text = 'Tukey';
            app.TriangularButton2 = uiradiobutton(app.FrecMediasBajasButtonGroup);
            app.TriangularButton2.Text = 'Triangular';
            app.magnitudSlider_2Label = uilabel(app.UIFigure);
            app.magnitudSlider_2Label.Text = 'magnitud';
            app.FrecMediasBajasMagnitud = uislider(app.UIFigure);
            app.FrecMediasBajasMagnitud.Limits = [0 2];
            app.FrecMediasBajasMagnitud.Value = 1;
            app.FrecMediasAltasButtonGroup = uibuttongroup(app.UIFigure);
            app.FrecMediasAltasButtonGroup.Title = 'Frec Medias Altas';
            app.HanningButton3 = uiradiobutton(app.FrecMediasAltasButtonGroup);
            app.HanningButton3.Text = 'Hanning';
            app.HanningButton3.Value = true;
            app.RectangularButton3 = uiradiobutton(app.FrecMediasAltasButtonGroup);
            app.RectangularButton3.Text = 'Rectangular';
            app.TukeyButton3 = uiradiobutton(app.FrecMediasAltasButtonGroup);
            app.TukeyButton3.Text = 'Tukey';
            app.TriangularButton3 = uiradiobutton(app.FrecMediasAltasButtonGroup);
            app.TriangularButton3.Text = 'Triangular';
            app.magnitudSlider_3Label = uilabel(app.UIFigure);
            app.magnitudSlider_3Label.Text = 'magnitud';
            app.FrecAltasButtonGroup = uibuttongroup(app.UIFigure);
            app.FrecAltasButtonGroup.Title = 'Frec Altas';
            app.HanningButton4 = uiradiobutton(app.FrecAltasButtonGroup);
            app.HanningButton4.Text = 'Hanning';
            app.HanningButton4.Value = true;
            app.RectangularButton4 = uiradiobutton(app.FrecAltasButtonGroup);
            app.RectangularButton4.Text = 'Rectangular';
            app.TukeyButton4 = uiradiobutton(app.FrecAltasButtonGroup);
            app.TukeyButton4.Text = 'Tukey';
            app.TriangularButton4 = uiradiobutton(app.FrecAltasButtonGroup);
            app.TriangularButton4.Text = 'Triangular';
            app.magnitudSlider_4Label = uilabel(app.UIFigure);
            app.magnitudSlider_4Label.Text = 'magnitud';
            app.FrecAltasMagnitud = uislider(app.UIFigure);
            app.FrecAltasMagnitud.Limits = [0 2];
            app.FrecAltasMagnitud.Value = 1;
            app.notrecordingLabel = uilabel(app.UIFigure);
            app.notrecordingLabel.Text = 'not recording';
            app.ADDButton = uibutton(app.UIFigure, 'push');
            app.ADDButton.ButtonPushedFcn = createCallbackFcn(app, @ADDButtonPushed, true);
            app.ADDButton.Text = 'ADD';
            app.FrecMediasAltasMagnitud = uislider(app.UIFigure);
            app.FrecMediasAltasMagnitud.Limits = [0 2];
            app.FrecMediasAltasMagnitud.Value = 1;
            app.ADDButton_2 = uibutton(app.UIFigure, 'push');
            app.ADDButton_2.ButtonPushedFcn = createCallbackFcn(app, @ADDButton_2Pushed, true);
            app.ADDButton_2.Text = 'ADD';
            app.ADDButton_3 = uibutton(app.UIFigure, 'push');
            app.ADDButton_3.ButtonPushedFcn = createCallbackFcn(app, @ADDButton_3Pushed, true);
            app.ADDButton_3.Text = 'ADD';
            app.ADDButton_4 = uibutton(app.UIFigure, 'push');
            app.ADDButton_4.ButtonPushedFcn = createCallbackFcn(app, @ADDButton_4Pushed, true);
            app.ADDButton_4.Text = 'ADD';
            app.GRAPHButton = uibutton(app.UIFigure, 'push');
            app.GRAPHButton.ButtonPushedFcn = createCallbackFcn(app, @GRAPHButtonPushed, true);
            app.GRAPHButton.Text = 'GRAPH';
            app.GRAPHButton_2 = uibutton(app.UIFigure, 'push');
            app.GRAPHButton_2.ButtonPushedFcn = createCallbackFcn(app, @GRAPHButton_2Pushed, true);
            app.GRAPHButton_2.Text = 'GRAPH';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EC

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end